classdef modeAnalysisApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        States                     matlab.ui.control.Table
        LoadFromWorkspaceButton    matlab.ui.control.Button
        LinearModelEditField       matlab.ui.control.EditField
        LinearModelEditFieldLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        linearModel % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadFromWorkspaceButton
        function LoadFromWorkspaceButtonPushed(app, event)
            app.linearModel = evalin('base', app.LinearModelEditField.Value);

           
            stateData = table(string(zeros(numel(app.linearModel.StateName), 1)), ...
                string(zeros(numel(app.linearModel.StateName), 1)),...
                zeros(numel(app.linearModel.StateName), 1), ...
                false(numel(app.linearModel.StateName), 1));
            stateData.Properties.VariableNames = {'Type', 'Name', 'Value', 'Plot'};

            for iState = 1:numel(app.linearModel.StateName)
                stateData.Name(iState) = app.linearModel.StateName{iState};
                stateData.Type(iState) = 'State';
            end

            app.States.Data             = stateData;
            app.States.ColumnName       = stateData.Properties.VariableNames;
            app.States.ColumnEditable   = [false false true true];

%             if ~isempty(app.Outputs.Children)
%                 delete(app.Outputs.Children);
%             end
%             
%             for iOut = 1:numel(app.linearModel.OutputName)
%                 uitreenode(app.Outputs, 'Text', app.linearModel.OutputName{iOut});
%             end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LinearModelEditFieldLabel = uilabel(app.UIFigure);
            app.LinearModelEditFieldLabel.Text = 'Linear Model';
            app.LinearModelEditField = uieditfield(app.UIFigure, 'text');
            app.LinearModelEditField.Value = 'spMode';
            app.LoadFromWorkspaceButton = uibutton(app.UIFigure, 'push');
            app.LoadFromWorkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFromWorkspaceButtonPushed, true);
            app.LoadFromWorkspaceButton.Text = 'Load From Workspace';
            app.States = uitable(app.UIFigure);
            app.States.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.States.RowName = {};
            app.States.ColumnEditable = true;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = modeAnalysisApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end